classdef perceive_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        saveandexitButton           matlab.ui.control.Button
        StimContacts                matlab.ui.control.EditField
        ContactsLabel               matlab.ui.control.Label
        StimLabelSpec               matlab.ui.control.EditField
        BurstdurationOnmsLabel_4    matlab.ui.control.Label
        StimLabelPulse              matlab.ui.control.EditField
        BurstdurationOnmsLabel_3    matlab.ui.control.Label
        StimLabelFreq               matlab.ui.control.EditField
        BurstdurationOnmsLabel_2    matlab.ui.control.Label
        StimDropDown                matlab.ui.control.DropDown
        BurstDurOff                 matlab.ui.control.EditField
        BurstdurationOffmsEditFieldLabel  matlab.ui.control.Label
        StimLabel                   matlab.ui.control.EditField
        OtherstimlabelvalueEditFieldLabel  matlab.ui.control.Label
        BurstFreq                   matlab.ui.control.EditField
        BurstfreqHzEditFieldLabel   matlab.ui.control.Label
        BurstDurOn                  matlab.ui.control.EditField
        BurstdurationOnmsLabel      matlab.ui.control.Label
        removeButton                matlab.ui.control.Button
        AdvancedoptionsLabel        matlab.ui.control.Label
        stimLabel                   matlab.ui.control.Label
        FilecurrentlyselectedLabel  matlab.ui.control.Label
        partQuestion                matlab.ui.control.Label
        runQuestion                 matlab.ui.control.Label
        partDropDown                matlab.ui.control.DropDown
        partDropDownLabel           matlab.ui.control.Label
        runDropDown                 matlab.ui.control.DropDown
        runDropDownLabel            matlab.ui.control.Label
        redoButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        filenameLabel               matlab.ui.control.Label
        saveandcontinueButton       matlab.ui.control.Button
        TaskDropDown                matlab.ui.control.DropDown
        taskDropDownLabel           matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        testMenu                    matlab.ui.container.Menu
        stitchtogetherMenu          matlab.ui.container.Menu
    end

    properties (Access = public)
        MetaT
        localsettings
    end
    properties (Access = private)
       
        numberoffiles=0% Description
        iterator=1;
    end

    methods
        function nextrow(app)
            %%%% update the fields with the new line
            app.filenameLabel.Text = app.MetaT.perceiveFilename(app.iterator);
            app.TaskDropDown.Value = app.MetaT.task{app.iterator};
            app.runDropDown.Value = app.MetaT.run{app.iterator};
            app.partDropDown.Value = app.MetaT.part{app.iterator};
            app.StimDropDown.Value = char(extract(app.MetaT.acq{app.iterator}, ("StimOff"|"StimX"|"StimOnL"|"StimOnR"|"StimOnB"|"StimOnB"|"BurstL"|"BurstR"|"BurstB"|"StimOn1L"|"StimOn1aL"|"StimOn1bL"|"StimOn1cL"|"StimOn2L"|"StimOn2aL"|"StimOn2bL"|"StimOn2cL"|"StimOn1R"|"StimOn1aR"|"StimOn1bR"|"StimOn1cR"|"StimOn2R"|"StimOn2aR"|"StimOn2bR"|"StimOn2cR")));
            app.StimContacts.Value = char(regexp(app.MetaT.acq{app.iterator}, '([123][abc])+', 'match', 'once'));
            app.BurstDurOn.Value = char(extract(app.MetaT.acq{app.iterator}, 'DurOn' + digitsPattern()));
            app.BurstDurOff.Value = char(extract(app.MetaT.acq{app.iterator}, 'DurOff' + digitsPattern()));
            app.BurstFreq.Value = char(extract(app.MetaT.acq{app.iterator}, 'Freq' + digitsPattern()));
            app.StimLabelFreq.Value = char(extract(app.MetaT.acq{app.iterator},  digitsPattern() + 'Hz'));
            app.StimLabelPulse.Value = char(extract(app.MetaT.acq{app.iterator}, digitsPattern() + 'us'));
            app.StimLabelSpec.Value = char(extract(app.MetaT.acq{app.iterator}, ("Jit"|"InvPol"|"varHz")));

            %%%% update the button text
            if app.iterator == app.numberoffiles
                app.saveandcontinueButton.Text = 'Save and exit';
            else
                app.saveandcontinueButton.Text = 'Save and continue';
            end
            if strcmp(app.MetaT.remove{app.iterator},'REMOVE')
                app.removeButton.Text = 'keep';
            else
                app.removeButton.Text = 'remove';
            end

            %%%% update the color coding
            removeStyle(app.UITable)
            s = uistyle("BackgroundColor",[1 0.6 0.6]);
            row = [app.iterator;app.iterator;app.iterator];
            col = [2;5;7];
            addStyle(app.UITable,s,"cell",[row,col]);
            %%%% update the UITable.Data %%% VERY IMPORTANT
            app.UITable.Data = app.MetaT;
        end
        
        function saving_call(app)
            app.MetaT.task(app.iterator) = {app.TaskDropDown.Value};
            app.MetaT.run(app.iterator) = {app.runDropDown.Value};
            if ~strcmp({app.partDropDown.Value},'None')
                 app.MetaT.part(app.iterator) = {app.partDropDown.Value};
            else
                app.MetaT.part(app.iterator) = {''};
            end
            app.MetaT.acq(app.iterator) = {[app.StimDropDown.Value app.StimContacts.Value app.BurstDurOn.Value app.BurstDurOff.Value app.BurstFreq.Value app.StimLabelFreq.Value app.StimLabelPulse.Value app.StimLabelSpec.Value app.StimLabel.Value]};           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MetaT, localsettings)
            if ~exist('MetaT', 'var')
                warning('now loading an example MetaT.mat')
                load('Example_dataset.mat', 'MetaT')
            end
            %if ~exist('Settings', 'var')
            %    warning('now loading default settings from Default_settings.mat')
            %    load('Default_settings.mat', 'Settings')
            %end
            %app.TaskDropDown.Items = {'Rest', 'CustomLabel', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9', 'XXXXX'};
            % Populate TaskDropDown with institution-specific taskItems
            app.TaskDropDown.Items = localsettings.taskItems;
            if ~isempty(localsettings.taskItems)
                app.TaskDropDown.Value = localsettings.taskItems{1};
            end

            % Populate StimDropDown with institution-specific stimItems
            app.StimDropDown.Items = localsettings.stimItems;
            if ~isempty(localsettings.stimItems)
                app.StimDropDown.Value = localsettings.stimItems{1};
            end
            
            % In StartupFcn
            app.UIFigure.CloseRequestFcn = @(src,event) ...
                set(app.saveandexitButton,'UserData','closed');

            app.MetaT = MetaT;
            app.UITable.Data = app.MetaT;
            app.UITable.ColumnWidth = 'fit'; 
            app.numberoffiles=length(app.MetaT.perceiveFilename);
            nextrow(app);
        end

        % Button pushed function: saveandcontinueButton
        function saveandcontinueButtonPushed(app, event)
            if app.iterator ~= app.numberoffiles
                saving_call(app);
                app.iterator = app.iterator + 1;
                nextrow(app);
            else
                saveandexitButtonPushed(app, event)
            end
        end

        % Button pushed function: saveandexitButton
        function saveandexitButtonPushed(app, event)
            %%% SAVE last changes
            saving_call(app);
            
            %%% UPDATE entire table
            for i = 1:app.numberoffiles
                    
                task=regexp(app.MetaT.perceiveFilename{i},'(?<=task-).*(?=_acq)','match');
                task=['task-' task{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},task,['task-' app.MetaT.task{i}]);
                run=regexp(app.MetaT.perceiveFilename{i},'(?<=run-)([0-9]*)','match');
                run = ['run-' run{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},run,['run-' app.MetaT.run{i}]);
                acq=regexp(app.MetaT.perceiveFilename{i},'(?<=acq-).*(?=_mod)','match');
                acq=['acq-' acq{1}];
                app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},acq,['acq-' app.MetaT.acq{i}]);
                if app.MetaT.part{i}
                    if contains(app.MetaT.perceiveFilename{i}, 'part')
                        part=regexp(app.MetaT.perceiveFilename{i},'(?<=part-)[1-9]*','match');
                        part=['part-' part{1}];
                        app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},part,['part-' app.MetaT.part{i}]);
                    else
                        app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},'.mat',['_part-' app.MetaT.part{i} '.mat']);
                    end
                end
            end
            
            %%%% preset the check conditions before going to EXIT
            problem_parts  = false;
            concat         = false;
            problemfilenr  = 0;
            errors         = {};
            maskKeep = ~strcmp(app.MetaT.remove,'REMOVE');   % only keep non-removed rows

            %%%%%%%% CHECK FOR CONCATENATION AND PART NR %%%%%%%%

            for i = 1:app.numberoffiles
                if problem_parts, break; end   % stop if already flagged
                if ~maskKeep(i), continue; end % skip rows marked REMOVE

                fname   = app.MetaT.perceiveFilename{i};
                partStr = app.MetaT.part{i};
                partNum = str2double(partStr);

                % skip if not numeric
                if isnan(partNum), continue; end

                % --- General rule: part N must have part N-1 ---
                if partNum > 1
                    prevFile = strrep(fname, ...
                        sprintf('part-%d.mat',partNum), ...
                        sprintf('part-%d.mat',partNum-1));
                    if ~any(app.MetaT.perceiveFilename(maskKeep) == string(prevFile))
                        errors{end+1} = sprintf('\nMissing part %d for file: %s',partNum-1,fname);
                        problem_parts = true;
                        problemfilenr = i;
                    end
                end

                % --- Special checks for part 1 ---
                if partNum == 1
                    % must have part 2
                    nextFile = strrep(fname,'part-1.mat','part-2.mat');
                    if ~any(app.MetaT.perceiveFilename(maskKeep) == string(nextFile))
                        errors{end+1} = sprintf('\nMissing part 2 for file: %s',fname);
                        problem_parts = true;
                        problemfilenr = i;
                    end

                    % check duplicate after concatenation
                    hittot = strrep(fname,'_part-1','');
                    if any(app.MetaT.perceiveFilename(maskKeep) == string(hittot))
                        errors{end+1} = sprintf('\nDuplicate run numbers after concatenation: %s',fname);
                        problem_parts = true;
                        problemfilenr = i;
                    end

                    concat = true;
                end
            end

            % --- Uniqueness check (ignoring REMOVE rows) ---
            [uniqNames,~,idx] = unique(app.MetaT.perceiveFilename(maskKeep),'stable');
            dupNames = uniqNames(accumarray(idx,1) > 1);

            if ~isempty(dupNames)
                % find first offending row in original table
                dupMask = ismember(app.MetaT.perceiveFilename,dupNames) & maskKeep;
                problemfilenr = find(dupMask,1,'first');

                errors{end+1} = sprintf('\nNon-unique filename detected: %s',dupNames{1});
                problem_parts = true;
            end

            % --- Concatenation confirmation ---
            if ~problem_parts && concat
                % keep only rows with numeric part numbers
                maskPart = ~isnan(str2double(app.MetaT.part));
                % combine both masks
                maskValid = maskKeep & maskPart;

                % collect filenames that satisfy both conditions
                filesWithPart = app.MetaT.perceiveFilename(maskValid);

                % build confirmation text
                confirmText = sprintf("Concatenate files?\n%s", strjoin(filesWithPart, newline));

                answer = questdlg(confirmText, 'Stitching files', 'Proceed', 'Abort', 'Proceed');
                if strcmp(answer,'Abort')
                    errors{end+1} = 'Concatenation aborted by user.';
                end
            end

            % --- Show one dialog if errors ---
            if ~isempty(errors)
                errordlg(strjoin(errors,newline),'File Errors');
                if problemfilenr
                    app.iterator = problemfilenr;
                else
                    app.iterator = 1;
                end
                app.UITable.Data = app.MetaT;
                nextrow(app);
                app.saveandcontinueButton.Text = 'Save and continue';
            else %%% EXIT THE GUI HERE
                app.saveandexitButton.UserData='Done'; %%% continue to main app  
            end
        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.saveandcontinueButton.Text = 'Save and continue';
            app.iterator = 1;
            nextrow(app);
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            if strcmp(app.removeButton.Text,'remove')
                app.TaskDropDown.Value = 'XXXXX';
                app.runDropDown.Value = '999';
                app.MetaT.remove{app.iterator} = 'REMOVE';
                saveandcontinueButtonPushed(app, event)
            else
                app.TaskDropDown.Value = 'TASK1';
                app.runDropDown.Value = '1';
                app.MetaT.remove{app.iterator} = 'keep';
                %saveandcontinueButtonPushed(app, event)
                saving_call(app);
                nextrow(app);
                app.removeButton.Text = 'remove';
            end
        end

        % Value changed function: runDropDown
        function runDropDownValueChanged(app, event)
            valStr = app.runDropDown.Value;          % user input as string
            valNum = str2double(valStr);          % convert to number
        
            % Check validity: must be integer between 1 and 1000
            if isnan(valNum) || valNum ~= floor(valNum) || valNum < 1 || valNum > 1000
                uialert(app.UIFigure, ...
                    'Please enter a whole number between 1 and 1000.', ...
                    'Invalid Input');
                % Reset to a safe default
                app.runDropDown.Value = '1';
            else
                % Normalize formatting (remove leading zeros, decimals)
                app.runDropDown.Value = num2str(valNum);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.taskDropDownLabel = uilabel(app.UIFigure);
            app.taskDropDownLabel.Text = 'task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {'Rest', 'RestTaptest', 'RestTapCont', 'RestFace', 'FingerTap', 'FingerTapL', 'FingerTapR', 'Rota', 'UPDRS', 'MovArtWalk', 'MovArtStand', 'MovArtHead', 'MovArtArms', 'MonRevDyt', 'FTGRampUpThres', 'FTGFastRamp125Hz', 'FTG110Hz', 'FTGBilateral', 'FTG145Hz', 'MID', 'SMTS', 'MSST', 'DualTask', 'Sync1', 'Sync2', 'RestTapBlock', 'RestTapMonoRev', 'RestTapMonoRevNoBreaks', 'RestMovBlock', 'RestMovMonoRev', 'RestMovMonoRevNoBreaks', 'RestMonoRev', 'RestMonoRevNoBreaks', 'Entrainment', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9', 'TASK10', 'TASK11', 'TASK12', 'TASK13', 'TASK14', 'TASK15', 'XXXXX'};
            app.TaskDropDown.Value = 'Rest';
            app.saveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.saveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @saveandcontinueButtonPushed, true);
            app.saveandcontinueButton.Text = 'save and continue';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'''report'''; '''perceiveFilename'''; '''session'''; '''condition'''; '''task'''; '''contacts'''; '''run'''; '''part'''; '''acq'''; '''remove?'''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.UITable.Enable = 'off';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            app.runDropDownLabel = uilabel(app.UIFigure);
            app.runDropDownLabel.Text = 'run';
            app.runDropDown = uidropdown(app.UIFigure);
            app.runDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.runDropDown.Editable = 'on';
            app.runDropDown.ValueChangedFcn = createCallbackFcn(app, @runDropDownValueChanged, true);
            app.runDropDown.Tooltip = {''};
            app.runDropDown.Value = '1';
            app.partDropDownLabel = uilabel(app.UIFigure);
            app.partDropDownLabel.Text = 'part';
            app.partDropDown = uidropdown(app.UIFigure);
            app.partDropDown.Items = {'None', '', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.partDropDown.Tooltip = {''};
            app.partDropDown.Value = 'None';
            app.runQuestion = uilabel(app.UIFigure);
            app.runQuestion.Tooltip = {'A run number is always added, in contrast with the partnumber.'; ''; 'The runnumber is always 1 by default: for a new recording of the same subject, same condition, same task, same acquisition, increase the runnumber by 1.'; ''; 'For an interrupted recording, keep the runnumber equal and add or increase the part number instead (e.g. run 1 part 1, run 1 part 2).'; ''; 'See also https://bids.neuroimaging.io/'};
            app.runQuestion.Text = '?';
            app.partQuestion = uilabel(app.UIFigure);
            app.partQuestion.Tooltip = {'The partnumber is only applicable for recordings that were interrupted due to a technical issue.'; ''; 'partnumber of 0/None, means that the recording is complete (The partnumber will not appear).'; ''; 'Partnumber 1 and 2 means those recordings with the same task and run number needs to be stiched together [in that order] due to an interrumption. For now, parts until 9 interruptions can be stitched together.'; ''; 'Example:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-1.mat'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-2.mat'; ''; 'will be concatenated to:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1.mat'; ''; 'The files of the 2 parts and the file of the concatenation are stored.'; ''; 'See also https://bids.neuroimaging.io/'};
            app.partQuestion.Text = '?';
            app.FilecurrentlyselectedLabel = uilabel(app.UIFigure);
            app.FilecurrentlyselectedLabel.Text = 'File currently selected:';
            app.stimLabel = uilabel(app.UIFigure);
            app.stimLabel.Text = 'stim';
            app.AdvancedoptionsLabel = uilabel(app.UIFigure);
            app.AdvancedoptionsLabel.Text = 'Advanced options:';
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.BurstdurationOnmsLabel = uilabel(app.UIFigure);
            app.BurstdurationOnmsLabel.Text = 'Burst duration On (ms)';
            app.BurstDurOn = uieditfield(app.UIFigure, 'text');
            app.BurstDurOn.Placeholder = 'DurOn000';
            app.BurstfreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.BurstfreqHzEditFieldLabel.Text = 'Burst freq (Hz)';
            app.BurstFreq = uieditfield(app.UIFigure, 'text');
            app.BurstFreq.Placeholder = 'Freq000';
            app.OtherstimlabelvalueEditFieldLabel = uilabel(app.UIFigure);
            app.OtherstimlabelvalueEditFieldLabel.Text = 'Other stim label - value';
            app.StimLabel = uieditfield(app.UIFigure, 'text');
            app.StimLabel.Placeholder = 'Lab000';
            app.BurstdurationOffmsEditFieldLabel = uilabel(app.UIFigure);
            app.BurstdurationOffmsEditFieldLabel.Text = 'Burst duration Off (ms)';
            app.BurstDurOff = uieditfield(app.UIFigure, 'text');
            app.BurstDurOff.Placeholder = 'DurOff000';
            app.StimDropDown = uidropdown(app.UIFigure);
            app.StimDropDown.Items = {'StimOff', 'StimOnR', 'StimOnL', 'StimOnB', 'StimX', 'BurstL', 'BurstR', 'BurstB', 'StimOn1L', 'StimOn1aL', 'StimOn1bL', 'StimOn1cL', 'StimOn2L', 'StimOn2aL', 'StimOn2bL', 'StimOn2cL', 'StimOn1R', 'StimOn1aR', 'StimOn1bR', 'StimOn1cR', 'StimOn2R', 'StimOn2aR', 'StimOn2bR', 'StimOn2cR'};
            app.StimDropDown.Tooltip = {''};
            app.StimDropDown.Value = 'StimOff';
            app.BurstdurationOnmsLabel_2 = uilabel(app.UIFigure);
            app.BurstdurationOnmsLabel_2.Text = 'Freq (Hz)';
            app.StimLabelFreq = uieditfield(app.UIFigure, 'text');
            app.StimLabelFreq.Placeholder = '125Hz';
            app.BurstdurationOnmsLabel_3 = uilabel(app.UIFigure);
            app.BurstdurationOnmsLabel_3.Text = 'Pulse width ';
            app.StimLabelPulse = uieditfield(app.UIFigure, 'text');
            app.StimLabelPulse.Placeholder = '60us';
            app.BurstdurationOnmsLabel_4 = uilabel(app.UIFigure);
            app.BurstdurationOnmsLabel_4.Text = 'Spec';
            app.StimLabelSpec = uieditfield(app.UIFigure, 'text');
            app.StimLabelSpec.Placeholder = 'InvPol / Jit';
            app.ContactsLabel = uilabel(app.UIFigure);
            app.ContactsLabel.Text = 'Contacts';
            app.StimContacts = uieditfield(app.UIFigure, 'text');
            app.StimContacts.Placeholder = 'a1a2a3b1b2b3c1c2c3';
            app.saveandexitButton = uibutton(app.UIFigure, 'push');
            app.saveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @saveandexitButtonPushed, true);
            app.saveandexitButton.Text = {'save'; 'and exit'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.testMenu = uimenu(app.ContextMenu);
            app.testMenu.Text = 'test';
            app.stitchtogetherMenu = uimenu(app.ContextMenu);
            app.stitchtogetherMenu.Text = 'stitch together';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perceive_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end